classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        scale_s                  matlab.ui.control.Slider
        scale_lbl                matlab.ui.control.Label
        log_g                    matlab.ui.container.ButtonGroup
        o_btn                    matlab.ui.control.RadioButton
        inv_btn                  matlab.ui.control.RadioButton
        line_group               matlab.ui.container.ButtonGroup
        dr_btn                   matlab.ui.control.RadioButton
        dl_btn                   matlab.ui.control.RadioButton
        v_btn                    matlab.ui.control.RadioButton
        h_btn                    matlab.ui.control.RadioButton
        m_lbl                    matlab.ui.control.Label
        v_lbl                    matlab.ui.control.Label
        m_df                     matlab.ui.control.NumericEditField
        v_df                     matlab.ui.control.NumericEditField
        pp_lbl                   matlab.ui.control.Label
        sp_lbl                   matlab.ui.control.Label
        pp_df                    matlab.ui.control.NumericEditField
        sp_df                    matlab.ui.control.NumericEditField
        n_s                      matlab.ui.control.Slider
        n_lbl                    matlab.ui.control.Label
        d0_lbl                   matlab.ui.control.Label
        d0_s                     matlab.ui.control.Slider
        FourierPassButtonGroup   matlab.ui.container.ButtonGroup
        LowPassButton            matlab.ui.control.RadioButton
        HighPassButton           matlab.ui.control.RadioButton
        mask_df                  matlab.ui.control.NumericEditField
        mask_lbl                 matlab.ui.control.Label
        c_lbl                    matlab.ui.control.Label
        b_lbl                    matlab.ui.control.Label
        a_lbl                    matlab.ui.control.Label
        background               matlab.ui.control.Image
        b_df                     matlab.ui.control.NumericEditField
        c_df                     matlab.ui.control.NumericEditField
        a_df                     matlab.ui.control.NumericEditField
        clear_button             matlab.ui.control.Button
        redo_button              matlab.ui.control.Button
        undo_button              matlab.ui.control.Button
        save_button              matlab.ui.control.Button
        apply_button             matlab.ui.control.Button
        filter_lb                matlab.ui.control.ListBox
        browse_button            matlab.ui.control.Button
        FiltersListListBoxLabel  matlab.ui.control.Label
        BrightnessSliderLabel    matlab.ui.control.Label
        brightness_s             matlab.ui.control.Slider
        histogram                matlab.ui.control.UIAxes
        modified_image           matlab.ui.control.UIAxes
        original_image           matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function show_histo(app, img)
            [counts, bins] = imhist(img);
            bar(app.histogram, bins, counts, 'BarWidth', 1);
            xlabel(app.histogram, 'Pixel Intensity');
            ylabel(app.histogram, 'Frequency');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browse_button
        function browse_buttonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*'}, 'Select an image file');
            
            if ~(isequal(filename, 0) || isequal(filepath, 0))
                selected_image = imread(fullfile(filepath, filename));
                
                setappdata(0, 'pre', 0);
                imshow(selected_image, 'Parent', app.original_image);
                setappdata(0, 'original_img', selected_image);
                setappdata(0, 'modified_img', selected_image);
                show_histo(app, selected_image);
                imshow(selected_image,'Parent', app.modified_image);
                
                stack_undo = cell(1, 0);
                stack_undo{end + 1} = {selected_image, 0};
                setappdata(0, 'stack_undo', stack_undo);
                
                stack_redo = cell(1, 0);
                setappdata(0, 'stack_redo', stack_redo);
            end

        end

        % Button pushed function: apply_button
        function apply_buttonButtonPushed(app, event)
            selected_index = app.filter_lb.Value;
            stack_undo = getappdata(0, 'stack_undo');
            img = stack_undo{end};
            img = img{1};
                
            if selected_index == "1"
                img = to_gray(img);
            end
            
            if selected_index == "2"
                img = to_binary(img);
            end
            
            if selected_index == "3"
                img = negative(img);
            end
            
            if selected_index == "4"
                img = stretching(img);
            end
            
            if selected_index == "5"
                if app.o_btn.Value
                    img = log_transform(img, app.c_df.Value, 'o');
                else
                    img = log_transform(img, app.c_df.Value, 'i');
                end
            end
            
            if selected_index == "6"
                img = gamma_correction(img, app.c_df.Value);
            end
            
            if selected_index == "7"
                img = equalization(img);
            end
            
            if selected_index == "8"
                img = weighted_filter(img);
            end
            
            if selected_index == "9"
                img = mean_filter(img);
            end
            
            if selected_index == "10"
                value = app.mask_df.Value;
                
                if value == 0
                   value = 1; 
                end
                img = max_filter(img, value);
            end
             
            if selected_index == "11"
                value = app.mask_df.Value;
                
                if value == 0
                   value = 1; 
                end
                img = median_filter(img, value);
            end
             
            if selected_index == "12"
                value = app.mask_df.Value;
                
                if value == 0
                   value = 1; 
                end
                img = midpoint_filter(img, value);
            end
             
            if selected_index == "13"
                value = app.mask_df.Value;
                
                if value == 0
                   value = 1; 
                end
                img = min_filter(img, value);
            end
            
            if selected_index == "14"
                img = img + laplacian_filter(img);
            end
            
            if selected_index == "15"
                img = img + comp_laplacian_filter(img);
            end
            
            if selected_index == "16"
                img = img + prewitt_hor_filter(img);
            end
            
            if selected_index == "17"
                img = img + prewitt_ver_filter(img);
            end
            
            if selected_index == "18"
                img = sobel_hor_filter(img);
            end
            
            if selected_index == "19"
                img = img + sobel_ver_filter(img);
            end
            
            if selected_index == "20"
                img = point_sharpening(img);
            end
            
            if selected_index == "21"
                img = point_detection(img);
            end
            
            if selected_index == "22"
                if app.h_btn.Value
                    img = line_sharpening(img, 'H');
                end
                
                if app.v_btn.Value
                    img = line_sharpening(img, 'V');
                end
                
                if app.dl_btn.Value
                    img = line_sharpening(img, "DL");
                end
                
                if app.dr_btn.Value
                    img = line_sharpening(img, "DR");
                end
            end
            
            if selected_index == "23"
                img = fourier_transform(img);
            end
            
            if selected_index == "24"
                img = inverse_fourier_transform(img);
            end
            
            if selected_index == "25"
                if app.LowPassButton.Value
                    img = ideal_f(img, app.d0_s.Value, 'l');
                end
                if app.HighPassButton.Value
                    img = ideal_f(img, app.d0_s.Value, 'h');
                end
            end
            
            if selected_index == "26"
                if app.LowPassButton.Value
                    img = butterw_f(img, app.d0_s.Value, app.n_s.Value, 'l');
                end
                if app.HighPassButton.Value
                    img = butterw_f(img, app.d0_s.Value, app.n_s.Value, 'h');
                end
            end
            
            if selected_index == "27"
                if app.LowPassButton.Value
                    img = gaussian_ff(img, app.d0_s.Value, 'l');
                end
                if app.HighPassButton.Value
                    img = gaussian_ff(img, app.d0_s.Value, 'h');
                end
            end
            
            if selected_index == "28"
                img = saltandpepper_noise(img, app.sp_df.Value, app.pp_df.Value);
            end
            
            if selected_index == "29"
                img = uniform_noise(img, app.a_df.Value, app.b_df.Value);
            end
            
            if selected_index == "30"
                img = noise_gaussian(img, app.v_df.Value, app.m_df.Value);
            end
            
            if selected_index == "31"
                img = rayleigh_noise(img, app.a_df.Value, app.b_df.Value);
            end
            
            if selected_index == "32"
                img = exp_noise(img, app.a_df.Value);
            end
            
            if selected_index == "33"
                img = erlang_noise(img, app.a_df.Value, app.b_df.Value);
            end
            
            stack_undo{end + 1} = {img, selected_index};
            
            img = brightness(img, app.brightness_s.Value);
            
            setappdata(0, 'modified_img', img);
            
            setappdata(0, 'stack_undo', stack_undo);
            
            stack_redo = cell(1, 0);
            setappdata(0, 'stack_redo', stack_redo);
            
            imshow(img, 'Parent', app.modified_image);
        end

        % Button pushed function: undo_button
        function undo_buttonButtonPushed(app, event)
            stack_undo = getappdata(0, 'stack_undo');
            stack_redo = getappdata(0, 'stack_redo');
            if length(stack_undo) > 1
                i = stack_undo{end};
                
                stack_redo{end + 1} = i;
                
                stack_undo(end) = [];
                
                
                setappdata(0, 'stack_undo', stack_undo);
                setappdata(0, 'stack_redo', stack_redo);
                img = stack_undo{end};
                
                img{1} = brightness(img{1}, app.brightness_s.Value);
                setappdata(0, 'modified_img', img{1});
                
                imshow(img{1}, 'Parent', app.modified_image);
            end
        end

        % Button pushed function: clear_button
        function clear_buttonButtonPushed(app, event)
            original = getappdata(0, 'original_img');
            img = brightness(original, app.brightness_s.Value);
            
            imshow(img, 'Parent', app.modified_image);
            setappdata(0, 'modified_img', img);
            
            stack_undo = cell(1, 0);
            stack_undo{end + 1} = {original, 0};
            
            setappdata(0, 'stack_undo', stack_undo);
            
            stack_redo = cell(1, 0);
            setappdata(0, 'stack_redo', stack_redo);
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
            img = getappdata(0, 'modified_img');
            imwrite(img, 'img.jpg', 'Quality', 100);
        end

        % Button pushed function: redo_button
        function redo_buttonButtonPushed(app, event)
            stack_undo = getappdata(0, 'stack_undo');
            stack_redo = getappdata(0, 'stack_redo');
            if ~(isempty(stack_redo))
                i = stack_redo{end};
                img = i{1};
                stack_undo{end + 1} = i;
                stack_redo(end) = [];
                
                setappdata(0, 'stack_undo', stack_undo);
                setappdata(0, 'stack_redo', stack_redo);
                
                img = brightness(img, app.brightness_s.Value);
                setappdata(0, 'modified_img', img);
                
                imshow(img, 'Parent', app.modified_image);
            end
        end

        % Value changed function: brightness_s
        function brightness_sValueChanged(app, event)
            value = app.brightness_s.Value;
            img = getappdata(0, 'stack_undo');
            img = img{end};
            img = img{1};
            img = brightness(img, value);
            setappdata(0, 'modified_img', img);
            imshow(img, 'Parent', app.modified_image);
        end

        % Value changed function: filter_lb
        function filter_lbValueChanged(app, event)
            value = app.filter_lb.Value;
            app.a_df.Visible = 'off';   app.a_lbl.Visible = 'off';
            app.b_df.Visible = 'off';   app.b_lbl.Visible = 'off';
            app.c_df.Visible = 'off';   app.c_lbl.Visible = 'off';
            app.mask_df.Visible = 'off';   app.mask_lbl.Visible = 'off';
            app.FourierPassButtonGroup.Visible = 'off';
            app.line_group.Visible = 'off';
            app.d0_s.Visible = 'off';   app.d0_lbl.Visible = 'off';
            app.n_s.Visible = 'off';    app.n_lbl.Visible = 'off';
            app.pp_df.Visible = 'off';   app.pp_lbl.Visible = 'off';
            app.sp_df.Visible = 'off';   app.sp_lbl.Visible = 'off';
            app.v_df.Visible = 'off';   app.v_lbl.Visible = 'off';
            app.m_df.Visible = 'off';   app.m_lbl.Visible = 'off';
            app.scale_s.Visible = 'off';   app.scale_lbl.Visible = 'off';
            app.log_g.Visible = 'off';
            if value == "5" || value == "6"
                app.c_df.Visible = 'on';    app.c_lbl.Visible = 'on';
            end
            
            if value == "5"
                app.log_g.Visible = 'on';
            end
            
            if value == "10" || value == "11" || value == "12" || value == "13"
                app.mask_df.Visible = 'on';   app.mask_lbl.Visible = 'on';
            end
            
            if value == "22"
                app.line_group.Visible = 'on';
            end
            
            if value == "25" || value == "26" || value == "27"
                app.FourierPassButtonGroup.Visible = 'on';
                app.d0_s.Visible = 'on';    app.d0_lbl.Visible = 'on';
            end
            
            if value == "26"
                app.n_s.Visible = 'on';     app.n_lbl.Visible = 'on';
            end
            
            if value == "28"
                app.pp_df.Visible = 'on';   app.pp_lbl.Visible = 'on';
                app.sp_df.Visible = 'on';   app.sp_lbl.Visible = 'on';
            end
            
            if value == "29" || value == "33" || value == "31"
                app.a_df.Visible = 'on';   app.a_lbl.Visible = 'on';
                app.b_df.Visible = 'on';   app.b_lbl.Visible = 'on';
            end
            
            if value == "30"
                app.v_df.Visible = 'on';   app.v_lbl.Visible = 'on';
                app.m_df.Visible = 'on';   app.m_lbl.Visible = 'on';
            end
            
            if value == "32"
                app.a_df.Visible = 'on';   app.a_lbl.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_image = uiaxes(app.UIFigure);
            title(app.original_image, 'Original Image      ')
            app.original_image.XTick = [];
            app.original_image.YTick = [];
            app.modified_image = uiaxes(app.UIFigure);
            title(app.modified_image, 'Modified Image       ')
            app.modified_image.XTick = [];
            app.modified_image.YTick = [];
            app.histogram = uiaxes(app.UIFigure);
            title(app.histogram, 'Histogram')
            app.histogram.XTick = [];
            app.histogram.YTick = [];
            app.brightness_s = uislider(app.UIFigure);
            app.brightness_s.Limits = [-50 50];
            app.brightness_s.ValueChangedFcn = createCallbackFcn(app, @brightness_sValueChanged, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.FiltersListListBoxLabel = uilabel(app.UIFigure);
            app.FiltersListListBoxLabel.Text = 'Filters List';
            app.browse_button = uibutton(app.UIFigure, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_buttonPushed, true);
            app.browse_button.Text = 'Browse Image';
            app.filter_lb = uilistbox(app.UIFigure);
            app.filter_lb.Items = {'To Gray', 'To Binary', 'Negative', 'Contrast Stretching', 'Logarithmic Transform', 'Gamma Correction', 'Histogram Equalization', 'Weighted Filter', 'Mean Filter', 'Max Filter', 'Median Filter', 'Mid-Point Filter', 'Min Filter', 'Laplacian Filter', 'Composite Laplacian Filter', 'Prewitt (Horizontal)', 'Prewitt (Vertical)', 'Sobel (Horizontal)', 'Sobel (Vertical)', 'Point Sharpening', 'Point Detection', 'Line Sharpening', 'Fourier Transform', 'Inverse Fourier Transform', 'Ideal Filter', 'Butterworth Filter', 'Gaussian Filter', 'Salt and Papper', 'Uniform Noise', 'Gaussian Noise', 'Rayleigh Noise', 'Exponential Noise', 'Gamma Noise'};
            app.filter_lb.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36'};
            app.filter_lb.ValueChangedFcn = createCallbackFcn(app, @filter_lbValueChanged, true);
            app.filter_lb.Value = '1';
            app.apply_button = uibutton(app.UIFigure, 'push');
            app.apply_button.ButtonPushedFcn = createCallbackFcn(app, @apply_buttonButtonPushed, true);
            app.apply_button.Text = 'Apply Filter';
            app.save_button = uibutton(app.UIFigure, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Text = 'Save Image';
            app.undo_button = uibutton(app.UIFigure, 'push');
            app.undo_button.ButtonPushedFcn = createCallbackFcn(app, @undo_buttonButtonPushed, true);
            app.undo_button.Text = '↩';
            app.redo_button = uibutton(app.UIFigure, 'push');
            app.redo_button.ButtonPushedFcn = createCallbackFcn(app, @redo_buttonButtonPushed, true);
            app.redo_button.Text = '↪';
            app.clear_button = uibutton(app.UIFigure, 'push');
            app.clear_button.ButtonPushedFcn = createCallbackFcn(app, @clear_buttonButtonPushed, true);
            app.clear_button.Text = 'Clear Filters';
            app.a_df = uieditfield(app.UIFigure, 'numeric');
            app.a_df.Visible = 'off';
            app.c_df = uieditfield(app.UIFigure, 'numeric');
            app.c_df.Visible = 'off';
            app.b_df = uieditfield(app.UIFigure, 'numeric');
            app.b_df.Visible = 'off';
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = 'Browse_window.png';
            app.a_lbl = uilabel(app.UIFigure);
            app.a_lbl.Visible = 'off';
            app.a_lbl.Text = 'A Value';
            app.b_lbl = uilabel(app.UIFigure);
            app.b_lbl.Visible = 'off';
            app.b_lbl.Text = 'B Value';
            app.c_lbl = uilabel(app.UIFigure);
            app.c_lbl.Visible = 'off';
            app.c_lbl.Text = 'C Value';
            app.mask_lbl = uilabel(app.UIFigure);
            app.mask_lbl.Visible = 'off';
            app.mask_lbl.Text = 'Mask Size';
            app.mask_df = uieditfield(app.UIFigure, 'numeric');
            app.mask_df.Visible = 'off';
            app.mask_df.Value = 1;
            app.FourierPassButtonGroup = uibuttongroup(app.UIFigure);
            app.FourierPassButtonGroup.Title = 'Fourier Pass';
            app.FourierPassButtonGroup.Visible = 'off';
            app.HighPassButton = uiradiobutton(app.FourierPassButtonGroup);
            app.HighPassButton.Text = 'High Pass';
            app.HighPassButton.Value = true;
            app.LowPassButton = uiradiobutton(app.FourierPassButtonGroup);
            app.LowPassButton.Text = 'Low Pass';
            app.d0_s = uislider(app.UIFigure);
            app.d0_s.Limits = [0 15];
            app.d0_s.Visible = 'off';
            app.d0_lbl = uilabel(app.UIFigure);
            app.d0_lbl.Visible = 'off';
            app.d0_lbl.Text = 'D0 Value';
            app.n_lbl = uilabel(app.UIFigure);
            app.n_lbl.Visible = 'off';
            app.n_lbl.Text = 'N Value';
            app.n_s = uislider(app.UIFigure);
            app.n_s.Limits = [0 15];
            app.n_s.Visible = 'off';
            app.sp_df = uieditfield(app.UIFigure, 'numeric');
            app.sp_df.Visible = 'off';
            app.sp_df.Value = 0.005;
            app.pp_df = uieditfield(app.UIFigure, 'numeric');
            app.pp_df.Visible = 'off';
            app.pp_df.Value = 0.005;
            app.sp_lbl = uilabel(app.UIFigure);
            app.sp_lbl.Visible = 'off';
            app.sp_lbl.Text = 'SP Value';
            app.pp_lbl = uilabel(app.UIFigure);
            app.pp_lbl.Visible = 'off';
            app.pp_lbl.Text = 'PP Value';
            app.v_df = uieditfield(app.UIFigure, 'numeric');
            app.v_df.Visible = 'off';
            app.m_df = uieditfield(app.UIFigure, 'numeric');
            app.m_df.Visible = 'off';
            app.v_lbl = uilabel(app.UIFigure);
            app.v_lbl.Visible = 'off';
            app.v_lbl.Text = 'V Value';
            app.m_lbl = uilabel(app.UIFigure);
            app.m_lbl.Visible = 'off';
            app.m_lbl.Text = 'M Value';
            app.line_group = uibuttongroup(app.UIFigure);
            app.line_group.Title = 'Line Filter';
            app.line_group.Visible = 'off';
            app.h_btn = uiradiobutton(app.line_group);
            app.h_btn.Text = 'Horizontal';
            app.h_btn.Value = true;
            app.v_btn = uiradiobutton(app.line_group);
            app.v_btn.Text = 'Vertical';
            app.dl_btn = uiradiobutton(app.line_group);
            app.dl_btn.Text = 'DL';
            app.dr_btn = uiradiobutton(app.line_group);
            app.dr_btn.Text = 'DR';
            app.log_g = uibuttongroup(app.UIFigure);
            app.log_g.Title = 'Log';
            app.log_g.Visible = 'off';
            app.inv_btn = uiradiobutton(app.log_g);
            app.inv_btn.Text = 'Ordinary';
            app.inv_btn.Value = true;
            app.o_btn = uiradiobutton(app.log_g);
            app.o_btn.Text = 'Inverse';
            app.scale_lbl = uilabel(app.UIFigure);
            app.scale_lbl.Visible = 'off';
            app.scale_lbl.Text = 'Scale';
            app.scale_s = uislider(app.UIFigure);
            app.scale_s.Limits = [0 1];
            app.scale_s.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end