classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        save_button              matlab.ui.control.Button
        apply_button             matlab.ui.control.Button
        filter_lb                matlab.ui.control.ListBox
        browse_button            matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        BrightnessSliderLabel_2  matlab.ui.control.Label
        BrightnessSlider_2       matlab.ui.control.Slider
        FiltersListListBoxLabel  matlab.ui.control.Label
        BrightnessSliderLabel    matlab.ui.control.Label
        brightness_s             matlab.ui.control.Slider
        histogram                matlab.ui.control.UIAxes
        modified_image           matlab.ui.control.UIAxes
        original_image           matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function show_histo(app, img)
            [counts, bins] = imhist(img);
            bar(app.histogram, bins, counts, 'BarWidth', 1);
            xlabel(app.histogram, 'Pixel Intensity');
            ylabel(app.histogram, 'Frequency');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browse_button
        function browse_buttonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png';'*.bmp'}, 'Select an image file');
            
            if ~(isequal(filename, 0) || isequal(filepath, 0))
                selectedImage = imread(fullfile(filepath, filename));
                
                imshow(selectedImage, 'Parent', app.original_image);
                setappdata(0, 'originial_img', selectedImage);
                show_histo(app, selectedImage);
            end

        end

        % Callback function
        function SliderValueChanged(app, event)
            %value = app.brightness_s.Value;
            %if ~isempty(app.original_image.ImageSource)
             %   new_Image = brightness(app.original_image.ImageSource, value);
              %  app.modified_image.ImageSource = new_Image;
            %end
        end

        % Callback function
        function SliderValueChanging(app, event)
    
        end

        % Button pushed function: apply_button
        function apply_buttonButtonPushed(app, event)
            selected_index = app.filter_lb.Value;
            modified_img = getappdata(0, 'originial_img');
            if selected_index == '1'
                modified_img = rgb2gray(modified_img);
            end
            imshow(modified_img, 'Parent', app.modified_image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_image = uiaxes(app.UIFigure);
            title(app.original_image, 'Original Image')
            app.original_image.XTick = [];
            app.original_image.YTick = [];
            app.modified_image = uiaxes(app.UIFigure);
            title(app.modified_image, 'Modified Image')
            app.modified_image.XTick = [];
            app.modified_image.YTick = [];
            app.histogram = uiaxes(app.UIFigure);
            title(app.histogram, 'Histogram')
            app.histogram.XTick = [];
            app.histogram.YTick = [];
            app.brightness_s = uislider(app.UIFigure);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.FiltersListListBoxLabel = uilabel(app.UIFigure);
            app.FiltersListListBoxLabel.Text = 'Filters List';
            app.BrightnessSlider_2 = uislider(app.UIFigure);
            app.BrightnessSliderLabel_2 = uilabel(app.UIFigure);
            app.BrightnessSliderLabel_2.Text = 'Brightness';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Browse_window.png';
            app.browse_button = uibutton(app.UIFigure, 'push');
            app.browse_button.ButtonPushedFcn = createCallbackFcn(app, @browse_buttonPushed, true);
            app.browse_button.Text = 'Browse Image';
            app.filter_lb = uilistbox(app.UIFigure);
            app.filter_lb.Items = {'RGB to Gray', 'Gray to Binary', 'RGB to Binary'};
            app.filter_lb.ItemsData = {'1', '2', '3'};
            app.filter_lb.Value = {};
            app.apply_button = uibutton(app.UIFigure, 'push');
            app.apply_button.ButtonPushedFcn = createCallbackFcn(app, @apply_buttonButtonPushed, true);
            app.apply_button.Text = 'Apply Filter';
            app.save_button = uibutton(app.UIFigure, 'push');
            app.save_button.Text = 'Save Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end